classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        Tab_Input                   matlab.ui.container.Tab
        BrowseButton                matlab.ui.control.Button
        ChooseplatemapLabel         matlab.ui.control.Label
        ChooseplatemapEditField     matlab.ui.control.EditField
        TabGroup3                   matlab.ui.container.TabGroup
        Plate1Tab                   matlab.ui.container.Tab
        ExampleDataSet1Label        matlab.ui.control.Label
        PathtoImagesEditField       matlab.ui.control.EditField
        UITable                     matlab.ui.control.Table
        UITable_2                   matlab.ui.control.Table
        PathtoImagesLabel           matlab.ui.control.Label
        EditField                   matlab.ui.control.EditField
        RowsLabel                   matlab.ui.control.Label
        ColumnsLabel                matlab.ui.control.Label
        EditField_2                 matlab.ui.control.EditField
        EditField_3                 matlab.ui.control.EditField
        FieldsLabel_2               matlab.ui.control.Label
        EditField_4                 matlab.ui.control.EditField
        TimepointsLabel_3           matlab.ui.control.Label
        Button_29                   matlab.ui.control.Button
        CheckBox_17                 matlab.ui.control.CheckBox
        PlateMetadataLabel          matlab.ui.control.Label
        PlateMapLabel_2             matlab.ui.control.Label
        FilterInputLabel_2          matlab.ui.control.Label
        ImageChannelsLabel          matlab.ui.control.Label
        Button_30                   matlab.ui.control.Button
        Button_31                   matlab.ui.control.Button
        Button_32                   matlab.ui.control.Button
        ListBox_2                   matlab.ui.control.ListBox
        Tab2_2                      matlab.ui.container.Tab
        Button_27                   matlab.ui.control.Button
        Tab_Display                 matlab.ui.container.Tab
        DisplaySettingsLabel        matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        ImageSelectionLabel         matlab.ui.control.Label
        ChannelsLabel               matlab.ui.control.Label
        LabelOverlay                matlab.ui.control.Label
        MetricOverlayLabel          matlab.ui.control.Label
        Button_3                    matlab.ui.control.Button
        Button_16                   matlab.ui.control.Button
        Button_17                   matlab.ui.control.Button
        Button_18                   matlab.ui.control.Button
        ListBox                     matlab.ui.control.ListBox
        ColumnDropDownLabel         matlab.ui.control.Label
        ColumnDropDown              matlab.ui.control.DropDown
        FieldDropDownLabel          matlab.ui.control.Label
        TimepointDropDownLabel      matlab.ui.control.Label
        FieldDropDown               matlab.ui.control.DropDown
        TimepointDropDown           matlab.ui.control.DropDown
        maxLabel_9                  matlab.ui.control.Label
        Button_65                   matlab.ui.control.Button
        Button_66                   matlab.ui.control.Button
        minLabel_9                  matlab.ui.control.Label
        Slider_38                   matlab.ui.control.Slider
        Slider_39                   matlab.ui.control.Slider
        maxLabel_10                 matlab.ui.control.Label
        Slider_40                   matlab.ui.control.Slider
        Button_67                   matlab.ui.control.Button
        Button_68                   matlab.ui.control.Button
        CheckBox_31                 matlab.ui.control.CheckBox
        CheckBox_32                 matlab.ui.control.CheckBox
        Slider_41                   matlab.ui.control.Slider
        minLabel_10                 matlab.ui.control.Label
        CheckBox_30                 matlab.ui.control.CheckBox
        CellLabel_5                 matlab.ui.control.Label
        Button_63                   matlab.ui.control.Button
        Spinner_4                   matlab.ui.control.Spinner
        Slider_37                   matlab.ui.control.Slider
        Button_70                   matlab.ui.control.StateButton
        CheckBox_33                 matlab.ui.control.CheckBox
        CellLabel_6                 matlab.ui.control.Label
        Button_71                   matlab.ui.control.Button
        Slider_42                   matlab.ui.control.Slider
        Button_72                   matlab.ui.control.StateButton
        Spinner_5                   matlab.ui.control.Spinner
        Slider_43                   matlab.ui.control.Slider
        Button_Measure_3            matlab.ui.control.Button
        RowDropDownLabel            matlab.ui.control.Label
        RowDropDown                 matlab.ui.control.DropDown
        ExperimentDropDownLabel     matlab.ui.control.Label
        ExperimentDropDown          matlab.ui.control.DropDown
        PlateDropDownLabel          matlab.ui.control.Label
        PlateDropDown               matlab.ui.control.DropDown
        LineScanLabel               matlab.ui.control.Label
        CellLabel_7                 matlab.ui.control.Label
        CellLabel_8                 matlab.ui.control.Label
        Tab_Segment                 matlab.ui.container.Tab
        Button_AddSegment           matlab.ui.control.Button
        SegmentationSettingsLabel   matlab.ui.control.Label
        Tab_Measure                 matlab.ui.container.Tab
        Button_Measure_2            matlab.ui.control.Button
        ProcessingLogTextArea       matlab.ui.control.TextArea
        ProcessingLogTextAreaLabel  matlab.ui.control.Label
        TabGroup4                   matlab.ui.container.TabGroup
        Tab_2                       matlab.ui.container.Tab
        ExampleMeasurement1Label    matlab.ui.control.Label
        CheckBox_18                 matlab.ui.control.CheckBox
        ListBox_4                   matlab.ui.control.ListBox
        ListBox_3                   matlab.ui.control.ListBox
        ChannelstoMeasureLabel      matlab.ui.control.Label
        SegmentstoMeasureLabel      matlab.ui.control.Label
        Tab2_3                      matlab.ui.container.Tab
        PrimaryLabelDropDownLabel   matlab.ui.control.Label
        DropDown                    matlab.ui.control.DropDown
        SelectMeasurementsLabel     matlab.ui.control.Label
        Button_34                   matlab.ui.control.Button
        ProgressSlider              matlab.ui.control.Slider
        Tab_Track                   matlab.ui.container.Tab
        Tab_Plot                    matlab.ui.container.Tab
        SegmentLabel                matlab.ui.control.Label
        MadewithinTorontoLabel      matlab.ui.control.Label
        DanielSniderLabel           matlab.ui.control.Label
        Button_Segment              matlab.ui.control.Button
        MeasureLabel                matlab.ui.control.Label
        TrackLabel                  matlab.ui.control.Label
        PlotLabel                   matlab.ui.control.Label
        InputLabel                  matlab.ui.control.Label
        Button_Input                matlab.ui.control.Button
        Button_Plot                 matlab.ui.control.Button
        Button_Track                matlab.ui.control.Button
        Button_Measure              matlab.ui.control.Button
        SingleCellAnalysisWorkflowLabel  matlab.ui.control.Label
        KafriLabLabel               matlab.ui.control.Label
        Button_Plot_2               matlab.ui.control.Button
        DisplayLabel                matlab.ui.control.Label
    end


    properties (Access = public)
        img % currently displayed image
        image % new currently displayed image
        input_data
        image_names
        log_processing_message
        log_startup_message
        StartupLogTextArea
        segmentation
        segment
        display
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Setup
            set(0,'DefaultFigureWindowStyle','docked');
            warning('off','images:initSize:adjustingMag');
            warning('off','images:imshow:magnificationMustBeFitForDockedFigure');
            addpath(genpath('functions'));
            addpath(genpath('plugins'));
            
            % Initialize variables
            app.img = imread('images/example_cells/r02c02f01p01-ch1sk1fk1fl1.tiff');
            app.input_data = {};
            app.segment = {};
            app.segmentation = {};
            app.segmentation.tabgp = [];
            app.display = {};
            app.display.channel = {};
            app.display.channel_override = 0;
            
            % Initialize Logs
            app.log_processing_message = @log_processing_message;
            app.log_startup_message = @log_startup_message;            
                        
            % Display startup log
            startup_f = uifigure('Color', [.6 .6 .6], 'Position', [412 243 540 510]);
            app.StartupLogTextArea = uitextarea(startup_f,'Position', [5 5 530 500]);
            msg = sprintf('Initializing.');
            app.log_startup_message(app, msg);
                                    
            % Select tab to display on opening
            app.TabGroup.SelectedTab = app.Tab_Display;
            
            % Initialize Data Input Tab
            
            
            BrowseButtonPushed(app,'Update'); % trigger for testing
            
            delete(startup_f); % it's not working. Refactor the startup log into the platemap loading log and display the log textbox in the input tab while loading and delete when done. Right now it only displays after it's useful, after the loading is done
            
        end

        % Callback function
        function UITableCellEdit(app, event)
            update_plot(app);
        end

        % Callback function
        function ButtonPushed(app, event)
            c = uisetcolor([1 1 0],'Select a color');
        end

        % Button pushed function: Button_Segment
        function Button_SegmentPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Segment;
        end

        % Button pushed function: Button_Input
        function Button_InputPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Input;
        end

        % Button pushed function: Button_Measure
        function Button_MeasurePushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Measure;
        end

        % Button pushed function: Button_Track
        function Button_TrackPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Track;
        end

        % Button pushed function: Button_Plot
        function Button_PlotPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Plot;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % Update
            %[FileName,PathName,FilterIndex] = uigetfile('*');
            %if ~FileName
            %    return
            %end
            %app.ChooseplatemapEditField.Value = [PathName FileName];
            
            app.ChooseplatemapEditField.Value = 'C:\Users\daniel snider\Dropbox\Kafri\Projects\GUI\daniel\Camilla_Plate_Map.xlsx';
            
            %Parse 
            app.input_data.plates = parse_platemap(app.ChooseplatemapEditField.Value);
            
            % Draw
            draw_input_data(app.input_data.plates, app);
            
            % Parse More
            app.input_data.channel_map = {app.input_data.plates.Ch1; app.input_data.plates.Ch2; app.input_data.plates.Ch3; app.input_data.plates.Ch4};
            app.input_data.unique_channels = unique(app.input_data.channel_map);
            parse_image_names(app);
            
            
            % Initialize Display Tab
            msg = sprintf('Loading Figure Display Tab...');
            app.log_startup_message(app, msg);
            draw_display(app);
            update_figure(app);
            
            % Initialize Segmentation Tab
            msg = sprintf('Loading Segmentation Tab...');
            app.log_startup_message(app, msg);
            add_segment(app, @createCallbackFcn);
            %app.segment{1}.AlgorithmDropDown.ValueChangedFcn(app, 'Update') % trigger once
            
        end

        % Button pushed function: Button_Measure_2
        function Button_Measure_2Pushed(app, event)
            %% EXECUTE MAIN PROCESSING
            start_processing(app);
            
        end

        % Button pushed function: Button_Plot_2
        function Button_Display_Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Display;
        end

        % Button pushed function: Button_AddSegment
        function Button_AddSegmentPushed(app, event)
            add_segment(app,@createCallbackFcn);
        end

        % Value changed function: PlateDropDown
        function PlateDropDownValueChanged(app, event)
            %% Display new plate
            value = app.PlateDropDown.Value;
            draw_display(app);
            update_figure(app);
        end

        % Value changed function: RowDropDown
        function RowDropDownValueChanged(app, event)
            value = app.RowDropDown.Value;
            update_figure(app);
        end

        % Value changed function: ColumnDropDown
        function ColumnDropDownValueChanged(app, event)
            value = app.ColumnDropDown.Value;
            update_figure(app);
        end

        % Value changed function: FieldDropDown
        function FieldDropDownValueChanged(app, event)
            value = app.FieldDropDown.Value;
            update_figure(app);
        end

        % Value changed function: TimepointDropDown
        function TimepointDropDownValueChanged(app, event)
            value = app.TimepointDropDown.Value;
            update_figure(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_Input = uitab(app.TabGroup);
            app.Tab_Input.Title = '                          ';
            app.BrowseButton = uibutton(app.Tab_Input, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ChooseplatemapLabel = uilabel(app.Tab_Input);
            app.ChooseplatemapLabel.Text = 'Choose plate map:';
            app.ChooseplatemapEditField = uieditfield(app.Tab_Input, 'text');
            app.ChooseplatemapEditField.Editable = 'off';
            app.TabGroup3 = uitabgroup(app.Tab_Input);
            app.Plate1Tab = uitab(app.TabGroup3);
            app.Plate1Tab.Title = 'Plate 1';
            app.ExampleDataSet1Label = uilabel(app.Plate1Tab);
            app.ExampleDataSet1Label.Text = 'ExampleDataSet1';
            app.PathtoImagesEditField = uieditfield(app.Plate1Tab, 'text');
            app.PathtoImagesEditField.Editable = 'off';
            app.UITable = uitable(app.Plate1Tab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.Plate1Tab);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.RowName = {};
            app.PathtoImagesLabel = uilabel(app.Plate1Tab);
            app.PathtoImagesLabel.Text = 'Path to Images:';
            app.EditField = uieditfield(app.Plate1Tab, 'text');
            app.EditField.Value = '1-41';
            app.RowsLabel = uilabel(app.Plate1Tab);
            app.RowsLabel.Text = 'Rows:';
            app.ColumnsLabel = uilabel(app.Plate1Tab);
            app.ColumnsLabel.Text = 'Columns:';
            app.EditField_2 = uieditfield(app.Plate1Tab, 'text');
            app.EditField_2.Value = '1-269';
            app.EditField_3 = uieditfield(app.Plate1Tab, 'text');
            app.EditField_3.Value = '1-269';
            app.FieldsLabel_2 = uilabel(app.Plate1Tab);
            app.FieldsLabel_2.Text = 'Fields:';
            app.EditField_4 = uieditfield(app.Plate1Tab, 'text');
            app.EditField_4.Value = '1-269';
            app.TimepointsLabel_3 = uilabel(app.Plate1Tab);
            app.TimepointsLabel_3.Text = 'Timepoints:';
            app.Button_29 = uibutton(app.Plate1Tab, 'push');
            app.Button_29.Icon = 'question-sign.png';
            app.Button_29.Text = '';
            app.CheckBox_17 = uicheckbox(app.Plate1Tab);
            app.CheckBox_17.Text = '';
            app.CheckBox_17.Value = true;
            app.PlateMetadataLabel = uilabel(app.Plate1Tab);
            app.PlateMetadataLabel.Text = 'Plate Metadata:';
            app.PlateMapLabel_2 = uilabel(app.Plate1Tab);
            app.PlateMapLabel_2.Text = 'Plate Map:';
            app.FilterInputLabel_2 = uilabel(app.Plate1Tab);
            app.FilterInputLabel_2.Text = 'Filter Input:';
            app.ImageChannelsLabel = uilabel(app.Plate1Tab);
            app.ImageChannelsLabel.Text = 'Image Channels';
            app.Button_30 = uibutton(app.Plate1Tab, 'push');
            app.Button_30.Icon = 'question-sign.png';
            app.Button_30.Text = '';
            app.Button_31 = uibutton(app.Plate1Tab, 'push');
            app.Button_31.Icon = 'question-sign.png';
            app.Button_31.Text = '';
            app.Button_32 = uibutton(app.Plate1Tab, 'push');
            app.Button_32.Icon = 'question-sign.png';
            app.Button_32.Text = '';
            app.ListBox_2 = uilistbox(app.Plate1Tab);
            app.ListBox_2.Items = {'Ch1: DAPI', 'Ch2: SE', 'Ch3: ROS', 'Ch4: HA2X'};
            app.ListBox_2.Value = 'Ch1: DAPI';
            app.Tab2_2 = uitab(app.TabGroup3);
            app.Tab2_2.Title = 'Tab2';
            app.Button_27 = uibutton(app.Tab_Input, 'push');
            app.Button_27.Icon = 'question-sign.png';
            app.Button_27.Text = '';
            app.Tab_Display = uitab(app.TabGroup);
            app.Tab_Display.Title = '                          ';
            app.DisplaySettingsLabel = uilabel(app.Tab_Display);
            app.DisplaySettingsLabel.Text = 'Display Settings';
            app.Panel = uipanel(app.Tab_Display);
            app.ImageSelectionLabel = uilabel(app.Panel);
            app.ImageSelectionLabel.Text = 'Image Selection';
            app.ChannelsLabel = uilabel(app.Panel);
            app.ChannelsLabel.Text = 'Channels';
            app.LabelOverlay = uilabel(app.Panel);
            app.LabelOverlay.Text = 'Label Overlay';
            app.MetricOverlayLabel = uilabel(app.Panel);
            app.MetricOverlayLabel.Text = 'Metric Overlay';
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.Icon = 'camera.png';
            app.Button_3.Enable = 'off';
            app.Button_3.Text = '';
            app.Button_16 = uibutton(app.Panel, 'push');
            app.Button_16.Icon = '010-check.png';
            app.Button_16.Enable = 'off';
            app.Button_16.Text = '';
            app.Button_17 = uibutton(app.Panel, 'push');
            app.Button_17.Enable = 'off';
            app.Button_17.Text = '🏿';
            app.Button_18 = uibutton(app.Panel, 'push');
            app.Button_18.Enable = 'off';
            app.Button_18.Text = '📏';
            app.ListBox = uilistbox(app.Panel);
            app.ListBox.Items = {'Eccentricity', 'Area', 'Total Intensity', 'Mean Intensity'};
            app.ListBox.Value = 'Eccentricity';
            app.ColumnDropDownLabel = uilabel(app.Panel);
            app.ColumnDropDownLabel.Text = 'Column';
            app.ColumnDropDown = uidropdown(app.Panel);
            app.ColumnDropDown.Editable = 'on';
            app.ColumnDropDown.ValueChangedFcn = createCallbackFcn(app, @ColumnDropDownValueChanged, true);
            app.FieldDropDownLabel = uilabel(app.Panel);
            app.FieldDropDownLabel.Text = 'Field';
            app.TimepointDropDownLabel = uilabel(app.Panel);
            app.TimepointDropDownLabel.Text = 'Timepoint';
            app.FieldDropDown = uidropdown(app.Panel);
            app.FieldDropDown.Editable = 'on';
            app.FieldDropDown.ValueChangedFcn = createCallbackFcn(app, @FieldDropDownValueChanged, true);
            app.TimepointDropDown = uidropdown(app.Panel);
            app.TimepointDropDown.Editable = 'on';
            app.TimepointDropDown.ValueChangedFcn = createCallbackFcn(app, @TimepointDropDownValueChanged, true);
            app.maxLabel_9 = uilabel(app.Panel);
            app.maxLabel_9.Text = 'max';
            app.Button_65 = uibutton(app.Panel, 'push');
            app.Button_65.Icon = 'eye.png';
            app.Button_65.Text = '';
            app.Button_66 = uibutton(app.Panel, 'push');
            app.Button_66.Icon = 'painter-palette.png';
            app.Button_66.Text = '';
            app.minLabel_9 = uilabel(app.Panel);
            app.minLabel_9.Text = 'min';
            app.Slider_38 = uislider(app.Panel);
            app.Slider_38.MajorTicks = [];
            app.Slider_38.MajorTickLabels = {};
            app.Slider_38.MinorTicks = [];
            app.Slider_39 = uislider(app.Panel);
            app.Slider_39.MajorTicks = [];
            app.Slider_39.MajorTickLabels = {};
            app.Slider_39.MinorTicks = [];
            app.maxLabel_10 = uilabel(app.Panel);
            app.maxLabel_10.Text = 'max';
            app.Slider_40 = uislider(app.Panel);
            app.Slider_40.MajorTicks = [];
            app.Slider_40.MajorTickLabels = {};
            app.Slider_40.MinorTicks = [];
            app.Button_67 = uibutton(app.Panel, 'push');
            app.Button_67.Icon = 'eye.png';
            app.Button_67.Text = '';
            app.Button_68 = uibutton(app.Panel, 'push');
            app.Button_68.Icon = 'painter-palette.png';
            app.Button_68.Text = '';
            app.CheckBox_31 = uicheckbox(app.Panel);
            app.CheckBox_31.Text = '';
            app.CheckBox_32 = uicheckbox(app.Panel);
            app.CheckBox_32.Text = '';
            app.Slider_41 = uislider(app.Panel);
            app.Slider_41.MajorTicks = [];
            app.Slider_41.MajorTickLabels = {};
            app.Slider_41.MinorTicks = [];
            app.minLabel_10 = uilabel(app.Panel);
            app.minLabel_10.Text = 'min';
            app.CheckBox_30 = uicheckbox(app.Panel);
            app.CheckBox_30.Text = '';
            app.CellLabel_5 = uilabel(app.Panel);
            app.CellLabel_5.Text = 'Cell Label';
            app.Button_63 = uibutton(app.Panel, 'push');
            app.Button_63.Icon = 'painter-palette.png';
            app.Button_63.Text = '';
            app.Spinner_4 = uispinner(app.Panel);
            app.Slider_37 = uislider(app.Panel);
            app.Slider_37.MajorTicks = [];
            app.Slider_37.MajorTickLabels = {};
            app.Slider_37.MinorTicks = [];
            app.Slider_37.Value = 100;
            app.Button_70 = uibutton(app.Panel, 'state');
            app.Button_70.Icon = 'check-box-empty.png';
            app.Button_70.Text = '';
            app.CheckBox_33 = uicheckbox(app.Panel);
            app.CheckBox_33.Text = '';
            app.CellLabel_6 = uilabel(app.Panel);
            app.CellLabel_6.Text = 'Cell Label';
            app.Button_71 = uibutton(app.Panel, 'push');
            app.Button_71.Icon = 'painter-palette.png';
            app.Button_71.Text = '';
            app.Slider_42 = uislider(app.Panel);
            app.Slider_42.MajorTicks = [];
            app.Slider_42.MajorTickLabels = {};
            app.Slider_42.MinorTicks = [];
            app.Slider_42.Value = 100;
            app.Button_72 = uibutton(app.Panel, 'state');
            app.Button_72.Icon = 'check-box-empty.png';
            app.Button_72.Text = '';
            app.Spinner_5 = uispinner(app.Panel);
            app.Slider_43 = uislider(app.Panel);
            app.Slider_43.MajorTicks = [];
            app.Slider_43.MajorTickLabels = {};
            app.Slider_43.MinorTicks = [];
            app.Button_Measure_3 = uibutton(app.Panel, 'push');
            app.Button_Measure_3.Icon = 'download-to-storage-drive.png';
            app.Button_Measure_3.Text = 'Save Figure';
            app.RowDropDownLabel = uilabel(app.Panel);
            app.RowDropDownLabel.Text = 'Row';
            app.RowDropDown = uidropdown(app.Panel);
            app.RowDropDown.Editable = 'on';
            app.RowDropDown.ValueChangedFcn = createCallbackFcn(app, @RowDropDownValueChanged, true);
            app.ExperimentDropDownLabel = uilabel(app.Panel);
            app.ExperimentDropDownLabel.Text = 'Experiment';
            app.ExperimentDropDown = uidropdown(app.Panel);
            app.PlateDropDownLabel = uilabel(app.Panel);
            app.PlateDropDownLabel.Text = 'Plate';
            app.PlateDropDown = uidropdown(app.Panel);
            app.PlateDropDown.ValueChangedFcn = createCallbackFcn(app, @PlateDropDownValueChanged, true);
            app.LineScanLabel = uilabel(app.Panel);
            app.LineScanLabel.Text = 'Line Scan';
            app.CellLabel_7 = uilabel(app.Panel);
            app.CellLabel_7.Text = 'Cell Label';
            app.CellLabel_8 = uilabel(app.Panel);
            app.CellLabel_8.Text = 'Cell Label';
            app.Tab_Segment = uitab(app.TabGroup);
            app.Tab_Segment.Title = '                          ';
            app.Button_AddSegment = uibutton(app.Tab_Segment, 'push');
            app.Button_AddSegment.ButtonPushedFcn = createCallbackFcn(app, @Button_AddSegmentPushed, true);
            app.Button_AddSegment.Text = '➕ Add Segment';
            app.SegmentationSettingsLabel = uilabel(app.Tab_Segment);
            app.SegmentationSettingsLabel.Text = 'Segmentation Settings';
            app.Tab_Measure = uitab(app.TabGroup);
            app.Tab_Measure.Title = '                          ';
            app.Button_Measure_2 = uibutton(app.Tab_Measure, 'push');
            app.Button_Measure_2.ButtonPushedFcn = createCallbackFcn(app, @Button_Measure_2Pushed, true);
            app.Button_Measure_2.Icon = 'play-button.png';
            app.Button_Measure_2.Text = 'Start Processing';
            app.ProcessingLogTextArea = uitextarea(app.Tab_Measure);
            app.ProcessingLogTextAreaLabel = uilabel(app.Tab_Measure);
            app.ProcessingLogTextAreaLabel.Text = 'Processing Log';
            app.TabGroup4 = uitabgroup(app.Tab_Measure);
            app.Tab_2 = uitab(app.TabGroup4);
            app.Tab_2.Title = 'Tab';
            app.ExampleMeasurement1Label = uilabel(app.Tab_2);
            app.ExampleMeasurement1Label.Text = 'ExampleMeasurement1';
            app.CheckBox_18 = uicheckbox(app.Tab_2);
            app.CheckBox_18.Text = '';
            app.CheckBox_18.Value = true;
            app.ListBox_4 = uilistbox(app.Tab_2);
            app.ListBox_4.Items = {'Ch1: DAPI', 'Ch2: SE', 'Ch3: ROS', 'Ch4: HA2X'};
            app.ListBox_4.Multiselect = 'on';
            app.ListBox_4.Value = {'Ch1: DAPI'};
            app.ListBox_3 = uilistbox(app.Tab_2);
            app.ListBox_3.Items = {'Nucleus', 'Nucleolus', 'Cell', 'Focci'};
            app.ListBox_3.Multiselect = 'on';
            app.ListBox_3.Value = {'Nucleus'};
            app.ChannelstoMeasureLabel = uilabel(app.Tab_2);
            app.ChannelstoMeasureLabel.Text = 'Channels to Measure:';
            app.SegmentstoMeasureLabel = uilabel(app.Tab_2);
            app.SegmentstoMeasureLabel.Text = 'Segments to Measure:';
            app.Tab2_3 = uitab(app.TabGroup4);
            app.Tab2_3.Title = 'Tab2';
            app.PrimaryLabelDropDownLabel = uilabel(app.Tab_Measure);
            app.PrimaryLabelDropDownLabel.Text = 'Primary Segment:';
            app.DropDown = uidropdown(app.Tab_Measure);
            app.DropDown.Items = {'Cell', 'Option 2', 'Option 3', 'Option 4'};
            app.DropDown.Value = 'Cell';
            app.SelectMeasurementsLabel = uilabel(app.Tab_Measure);
            app.SelectMeasurementsLabel.Text = 'Select Measurements';
            app.Button_34 = uibutton(app.Tab_Measure, 'push');
            app.Button_34.Icon = 'question-sign.png';
            app.Button_34.Text = '';
            app.ProgressSlider = uislider(app.Tab_Measure);
            app.ProgressSlider.Limits = [0 1];
            app.ProgressSlider.MajorTicks = [];
            app.ProgressSlider.MajorTickLabels = {};
            app.ProgressSlider.MinorTicks = [];
            app.ProgressSlider.Enable = 'off';
            app.Tab_Track = uitab(app.TabGroup);
            app.Tab_Track.Title = '                          ';
            app.Tab_Plot = uitab(app.TabGroup);
            app.Tab_Plot.Title = '                          ';
            app.SegmentLabel = uilabel(app.UIFigure);
            app.SegmentLabel.Text = 'Segment';
            app.MadewithinTorontoLabel = uilabel(app.UIFigure);
            app.MadewithinTorontoLabel.Text = 'Made with 💛 in Toronto';
            app.DanielSniderLabel = uilabel(app.UIFigure);
            app.DanielSniderLabel.Text = '@DanielSnider ツ';
            app.Button_Segment = uibutton(app.UIFigure, 'push');
            app.Button_Segment.ButtonPushedFcn = createCallbackFcn(app, @Button_SegmentPushed, true);
            app.Button_Segment.Text = '🏿';
            app.MeasureLabel = uilabel(app.UIFigure);
            app.MeasureLabel.Text = 'Measure';
            app.TrackLabel = uilabel(app.UIFigure);
            app.TrackLabel.Text = 'Track';
            app.PlotLabel = uilabel(app.UIFigure);
            app.PlotLabel.Text = 'Plot';
            app.InputLabel = uilabel(app.UIFigure);
            app.InputLabel.Text = 'Input';
            app.Button_Input = uibutton(app.UIFigure, 'push');
            app.Button_Input.ButtonPushedFcn = createCallbackFcn(app, @Button_InputPushed, true);
            app.Button_Input.Icon = '012-shapes.png';
            app.Button_Input.Text = '';
            app.Button_Plot = uibutton(app.UIFigure, 'push');
            app.Button_Plot.ButtonPushedFcn = createCallbackFcn(app, @Button_PlotPushed, true);
            app.Button_Plot.Icon = '001-dot-plot.png';
            app.Button_Plot.Text = '';
            app.Button_Track = uibutton(app.UIFigure, 'push');
            app.Button_Track.ButtonPushedFcn = createCallbackFcn(app, @Button_TrackPushed, true);
            app.Button_Track.Text = '➠';
            app.Button_Measure = uibutton(app.UIFigure, 'push');
            app.Button_Measure.ButtonPushedFcn = createCallbackFcn(app, @Button_MeasurePushed, true);
            app.Button_Measure.Text = '📏';
            app.SingleCellAnalysisWorkflowLabel = uilabel(app.UIFigure);
            app.SingleCellAnalysisWorkflowLabel.Text = 'Single Cell Analysis Workflow';
            app.KafriLabLabel = uilabel(app.UIFigure);
            app.KafriLabLabel.Text = '@Kafri-Lab 🔬';
            app.Button_Plot_2 = uibutton(app.UIFigure, 'push');
            app.Button_Plot_2.ButtonPushedFcn = createCallbackFcn(app, @Button_Display_Pushed, true);
            app.Button_Plot_2.Icon = 'desktop-monitor.png';
            app.Button_Plot_2.Text = '';
            app.DisplayLabel = uilabel(app.UIFigure);
            app.DisplayLabel.Text = 'Display';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end